classdef VariableFlowGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ActuationPanel                 matlab.ui.container.Panel
        GoButton                       matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        SaveasTXTButton_2              matlab.ui.control.Button
        SaveasMATButton_2              matlab.ui.control.Button
        SaveasPNGButton_2              matlab.ui.control.Button
        RunningLampLabel               matlab.ui.control.Label
        RunningLamp                    matlab.ui.control.Lamp
        DataSavingLabel                matlab.ui.control.Label
        STOPButton                     matlab.ui.control.StateButton
        OnlystopsafterLabel            matlab.ui.control.Label
        thecurrentcommandLabel         matlab.ui.control.Label
        bufferhasfinishedLabel         matlab.ui.control.Label
        executingLabel                 matlab.ui.control.Label
        ScenarioPanel                  matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        ChoosefileButton               matlab.ui.control.Button
        SaveasPNGButton                matlab.ui.control.Button
        SaveasTXTButton                matlab.ui.control.Button
        VerifyButton                   matlab.ui.control.Button
        UnverifiedLabel                matlab.ui.control.Label
        FlowpositionDropDownLabel      matlab.ui.control.Label
        FlowpositionDropDown           matlab.ui.control.DropDown
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        TestIDEditFieldLabel           matlab.ui.control.Label
        TestIDEditField                matlab.ui.control.EditField
        NewIDButton                    matlab.ui.control.Button
        ManualControlPanel             matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        SimpleTab                      matlab.ui.container.Tab
        VoltageVSliderLabel            matlab.ui.control.Label
        VoltageVSlider                 matlab.ui.control.Slider
        PositiondegEditField_2Label    matlab.ui.control.Label
        PositiondegEditField_2         matlab.ui.control.NumericEditField
        GoButtonManual                 matlab.ui.control.Button
        FullcloseButton                matlab.ui.control.Button
        ReadButton                     matlab.ui.control.Button
        CurrentpositiondegEditFieldLabel  matlab.ui.control.Label
        CurrentpositiondegEditField    matlab.ui.control.NumericEditField
        CurrentvoltageVEditFieldLabel  matlab.ui.control.Label
        CurrentvoltageVEditField       matlab.ui.control.NumericEditField
        RampCheckBox                   matlab.ui.control.CheckBox
        VoltageVEditField_2Label       matlab.ui.control.Label
        VoltageVEditField_2            matlab.ui.control.NumericEditField
        AdvancedTab                    matlab.ui.container.Tab
        Button_3                       matlab.ui.control.Button
        FullopenButton                 matlab.ui.control.Button
        FullclosetofullopenButton      matlab.ui.control.Button
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        RampTypeButtonGroup            matlab.ui.container.ButtonGroup
        SlopeVsButton                  matlab.ui.control.RadioButton
        TimesButton                    matlab.ui.control.RadioButton
        RampParameterLabel             matlab.ui.control.Label
        ConverterTab                   matlab.ui.container.Tab
        VoltageVEditFieldLabel         matlab.ui.control.Label
        VoltageVEditField              matlab.ui.control.NumericEditField
        PositiondegEditFieldLabel      matlab.ui.control.Label
        PositiondegEditField           matlab.ui.control.NumericEditField
        FlowlsEditFieldLabel           matlab.ui.control.Label
        FlowlsEditField                matlab.ui.control.NumericEditField
        OpeningClosingDropDownLabel    matlab.ui.control.Label
        OpeningClosingDropDown         matlab.ui.control.DropDown
        ConfigurationPanel             matlab.ui.container.Panel
        DeviceNameLabel                matlab.ui.control.Label
        DeviceNameEditField            matlab.ui.control.EditField
        NIRatesamplessEditFieldLabel   matlab.ui.control.Label
        NIRatesamplessEditField        matlab.ui.control.NumericEditField
        PositionTolerancedegEditFieldLabel  matlab.ui.control.Label
        PositionTolerancedegEditField  matlab.ui.control.NumericEditField
        ValveSpeedsVEditFieldLabel     matlab.ui.control.Label
        ValveSpeedsVEditField          matlab.ui.control.NumericEditField
        NISatusLampLabel               matlab.ui.control.Label
        NISatusLamp                    matlab.ui.control.Lamp
        CheckstatusButton              matlab.ui.control.Button
        V17Label                       matlab.ui.control.Label
        ValveControlSoftwareviaNIUSBLabel  matlab.ui.control.Label
        NEsnaultLabel                  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Matlab2018bx64Label            matlab.ui.control.Label
        UoAFluidsLabLabel              matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function  MoveValve(app)
            try
                s = daq.createSession('ni');
                
                % Add a analog output to send the orders to the piston,
                %2 AO (250 kS/s)
                %addAnalogInputChannel(s,deviceID,channelID,measurementType)
                [ch,~] = addAnalogOutputChannel(s,app.DeviceNameEditField, 0, 'Voltage');
                % Set a range of -10 to +10 volts. if not sure: ch.RangesAvailable
                ch.Range = [-10,10];
                s.Rate = app.NIRatesamplessEditField.Value;
                s.TriggersPerRun = 1;
                % Create and queue an output signal: ActualPos-->InitialPos
                
                % Obey to the table: app.UITable (which might be loaded manually or with Excel)
                queueOutputData(s,linspace(ActualPos, InitialPos, abs(InitialPos - ActualPos) ...
                    * app.ManualControlRTHspeedsVEditField.Value * s.Rate)');
                
                
                % Send signal and wait until finished
                startForeground(s);
                
                %Clean up
                removeChannel(s,1:length(s.Channels));
                clear('s');
                
                actualVoltage = ReadVoltage(app);
                
            catch e
                % Error handling
                disp(e);
                Message(1,1,0,'Error while MoveValve', 'KO', app.TestIDEditField);
                if strcmp(e.identifier, 'daq:Session:noDevicesAvailable')
                    
                    uialert(app.UIFigure,'NI device not found','Communication error');
                    %                     errordlg('NI device not found','Communication error');
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                elseif strcmp(e.identifier,'daq:general:unknownVendor')
                    
                    uialert(app.UIFigure,'NI packages not found','Package error');
                    %                     errordlg('NI packages not found','Package error');
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                end
            end
            
        end
        
        function MoveToVoltage(app, VoltageToGo)
            
            Message(1,1,0,['MoveToVoltage command issued with voltage: ' num2str(VoltageToGo)], 'UDEF', app.TestIDEditField);
            
            try
                s = daq.createSession('ni');
                
                % Add a analog output to send the orders to the piston,
                %2 AO (250 kS/s)
                %addAnalogInputChannel(s,deviceID,channelID,measurementType)
                [ch,~] = addAnalogOutputChannel(s,app.DeviceNameEditField.Value, 0, 'Voltage');
                % Set a range of -10 to +10 volts. if not sure: ch.RangesAvailable
                ch.Range = [-10,10];
                s.Rate = app.NIRatesamplessEditField.Value;
                s.TriggersPerRun = 1;
                % Create and queue an output signal: drirect, hard, step to target
                
                queueOutputData(s,VoltageToGo);
                
                % Send signal and wait until finished
                startForeground(s);
                
                %Clean up
                removeChannel(s,1:length(s.Channels));
                clear('s');
                
            catch e
                % Error handling
                disp(e);
                Message(1,1,0,'Error while MoveToVoltage', 'KO', app.TestIDEditField);
                if strcmp(e.identifier, 'daq:Session:noDevicesAvailable')
                    %                     errordlg('NI device not found','Communication error');
                    
                    uialert(app.UIFigure,'NI device not found','Communication error');
                elseif strcmp(e.identifier,'daq:general:unknownVendor')
                    %                     errordlg('NI packages not found','Package error');
                    
                    uialert(app.UIFigure,'NI packages not found','Package error');
                end
            end
        end
        
        function resultPosition = Voltage2Position(app, Voltage)
            resultPosition = Voltage * 90 / (10 - 0);
        end
        
        function resultVoltage = Position2Voltage(app, Position)
            resultVoltage = Position * (10 - 0) / 90;
        end
        
        function resultFlow = Position2Flow(app, Position)
            %see graph fwd
            x = Position;
%             p1 =   4.101e-14;
%             p2 =  -1.515e-11;
%             p3 =   2.196e-09;
%             p4 =  -1.598e-07;
%             p5 =   6.213e-06;
%             p6 =  -0.0001257;
%             p7 =    0.001239;
%             p8 =   -0.003808;
%             p9 =    0.002675;
%             
%             resultFlow = app.MaxFlowlsEditField.Value * (p1*x.^8 + p2*x.^7 + p3*x.^6 + p4*x.^5 +...
%                 p5*x.^4 + p6*x.^3 + p7*x.^2 + p8.*x + p9);

%             p1 =   4.101e-14;
%             p2 =  -1.515e-11;
%             p3 =   2.196e-09;

% Opening:
% p1 (high) to p7 (low)
% -0.031092364574723
% 1.080692742510490
% -14.986901448389085
% 1.053559433592098e+02
% -3.925566813897443e+02
% 7.483879662164078e+02
% -5.747836784836982e+02
% 
% Closing:
% p1 (high) to p7 (low)
% -0.052652066308834
% 1.498301820012750
% -16.724245990117971
% 93.088134656518875
% -2.724704235516784e+02
% 4.195154205718814e+02
% -2.615275210373131e+02

            p3 =  -0.001109;
            p2 =   0.1514;
            p1 =  -4.31;
            p0 =   35.8;
            p = [p3, p2, p1, p0]';
            x_v = [x^3, x^2, x^1, x^0]';
            
            resultFlow = dot(p,x_v);  
                
        end
        
        function resultPosition = Flow2Position(app, Flow)
            %x  =        Flow / app.MaxFlowlsEditField.Value;
            x = Flow;
%             p1 =  -4.119e+04;
%             p2 =   1.693e+05;
%             p3 =  -2.868e+05;
%             p4 =   2.586e+05;
%             p5 =   -1.34e+05;
%             p6 =   4.046e+04;
%             p7 =       -7071;
%             p8 =       783.4;
%             p9 =       2.934;
%             
%             resultPosition = (p1*x.^8 + p2*x.^7 + p3*x.^6 + p4*x.^5 +...
%                 p5*x.^4 + p6*x.^3 + p7*x.^2 + p8.*x + p9);
            
            p3 =   4.7e-05;
            p2 =   -0.006782;
            p1 =  0.7025;
            p0 =   22;
            p = [p3, p2, p1, p0]';
            x_v = [x^3, x^2, x^1, x^0]';
            
            resultPosition = dot(p,x_v);                 
        end
        
        function MoveToVoltagewithTime(app, InitialVoltage, FinalVoltage, TTTs)
            % This function is performing well if it used for a set of 1 ramp only
            %             TTTs is Time To Target in s
            try
                s = daq.createSession('ni');
                
                % Add a analog output to send the orders to the piston,
                %2 AO (250 kS/s)
                %addAnalogInputChannel(s,deviceID,channelID,measurementType)
                [ch,~] = addAnalogOutputChannel(s,app.DeviceNameEditField.Value, 0, 'Voltage');
                % Set a range of -10 to +10 volts. if not sure: ch.RangesAvailable
                ch.Range = [-10,10];
                s.Rate = app.NIRatesamplessEditField.Value;
                s.TriggersPerRun = 1;
                % Create and queue an output signal: drirect, hard, step to target
                
                queueOutputData(s,linspace(InitialVoltage,FinalVoltage,...
                    TTTs * s.Rate)');
                
                % Queue data as stable to make a hold in position for a duration specified by the user in the table
                
                % Send signal and wait until finished
                startForeground(s);
                
                %Clean up
                removeChannel(s,1:length(s.Channels));
                clear('s');
                
            catch e
                % Error handling
                disp(e);
                Message(1,1,0,'Error while MoveToVoltagewithTime', 'KO', app.TestIDEditField);
                if strcmp(e.identifier, 'daq:Session:noDevicesAvailable')
                    %                     errordlg('NI device not found','Communication error');
                    
                    uialert(app.UIFigure,'NI device not found','Communication error');
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                elseif strcmp(e.identifier,'daq:general:unknownVendor')
                    %                     errordlg('NI packages not found','Package error');
                    
                    uialert(app.UIFigure,'NI packages not found','Package error');
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                end
            end
        end
        
        function actualVoltage = ReadVoltage(app)
            
            try
                % Try to create a session with analog channels
                s = daq.createSession('ni');
                %% Read position
                
                [ch,~] = addAnalogInputChannel(s,app.DeviceNameEditField.Value, 0, 'Voltage');
                s.Channels.TerminalConfig = 'SingleEnded';
                ch.Range = [-10,10];
                s.Rate = app.NIRatesamplessEditField.Value;
                s.TriggersPerRun = 1;
                
                
                [data,~] = s.startForeground;
                actualVoltage = mean(data); %average of the input
                removeChannel(s,1:length(s.Channels));
                clear('s');
                
            catch e
                % Error handling
                disp(e);
                Message(1,1,0,'Error while ReadVoltage', 'KO', app.TestIDEditField);
                if strcmp(e.identifier, 'daq:Session:noDevicesAvailable')
                    
                    uialert(app.UIFigure,'Problem while reading from NI device','Communication error');
                    %errordlg('NI device not found','Communication error');
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                end
            end
        end
        
        
        function NiDeviceFoundComponentsUpdate(app, NIDevicePresent)
            % update the different widgets depending if a NiDevice has been found
            if NIDevicePresent == 1
                value = 'on';
            elseif NIDevicePresent == 0
                value = 'off';
            else
                % problem: the variable NIDevicePresent should only be 0 or 1
            end
            
            % Update the widgets
            app.FlowpositionDropDown.Enable         = value;
            app.ChoosefileButton.Enable             = value;
            
            app.VoltageVSlider.Enable               = value;
            app.FullcloseButton.Enable              = value;
            app.FullopenButton.Enable               = value;
            app.FullclosetofullopenButton.Enable    = value;
            app.PositiondegEditField_2.Enable       = value;
            app.VoltageVEditField_2.Enable          = value;
            app.Button_3.Enable                     = value;
            app.GoButtonManual.Enable               = value;
            app.ReadButton.Enable                   = value;
            app.CurrentpositiondegEditField.Enable  = value;
            app.CurrentvoltageVEditField.Enable     = value;
            app.RampCheckBox.Enable                 = value;
            
        end
        
        
        function sinleManualRead(app)
            
            %app.ReadButton.Enable   = 'off'; %disable the button to avoid multiple daq sessions
            %app.StatusLamp.Color    = 'g'; % working
            occured_error           = 0;
            %read position once
            tempReadVoltage = ReadVoltage(app);
            
            app.CurrentvoltageVEditField.Value = tempReadVoltage;
            tempReadPosition = Voltage2Position(app, tempReadVoltage);
            app.CurrentpositiondegEditField.Value = tempReadPosition;
            
            if tempReadPosition < 0 && tempReadPosition > 90
                occured_error = 1;
            end
            
            if ~occured_error
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
            else
                %Change lamp status
                app.StatusLamp.Color = 'r'; % error
                Message(1,1,0,'Error while sinleManualRead', 'KO', app.TestIDEditField);
            end
            %app.ReadButton.Enable = 'on'; % re-enable the button
            
        end
        
        function DisableForMovement(app)
            % disable the GoButtonManual button to avoid multiple call and bloc the NI device
            %disable the button until done
            app.VoltageVSlider.Enable               = 'off';
            app.FullcloseButton.Enable              = 'off';
            app.FullopenButton.Enable               = 'off';
            app.FullclosetofullopenButton.Enable    = 'off';
            app.PositiondegEditField_2.Enable       = 'off';
            app.VoltageVEditField_2.Enable          = 'off';
            app.GoButtonManual.Enable               = 'off';
            app.Button_3.Enable                     = 'off';
            app.GoButton.Enable                     = 'off';
            app.RampCheckBox.Enable                 = 'off';
            app.ReadButton.Enable                   = 'off';
            app.SaveasPNGButton.Enable 		    = 'off';
            app.VerifyButton.Enable  		    = 'off';
            app.SaveasTXTButton.Enable		    = 'off';
            app.SaveasMATButton_2.Enable            = 'off';
            app.SaveasTXTButton_2.Enable            = 'off';
            app.SaveasPNGButton_2.Enable            = 'off';
            
            % Signal motion Send signal and wait until finished
            app.RunningLamp.Color = 'r';
            
        end
        
        function EnableAfterMovement(app)
            %Re enable buttons after done
            %app.GoButton.Enable                     = 'on';
            app.VoltageVSlider.Enable               = 'on';
            app.FullcloseButton.Enable              = 'on';
            app.FullopenButton.Enable               = 'on';
            app.FullclosetofullopenButton.Enable    = 'on';
            app.PositiondegEditField_2.Enable       = 'on';
            app.VoltageVEditField_2.Enable          = 'on';
            app.GoButtonManual.Enable               = 'on';
            app.Button_3.Enable                     = 'on';
            app.RampCheckBox.Enable                 = 'on';
            app.ReadButton.Enable                   = 'on';
            app.SaveasMATButton_2.Enable            = 'on';
            app.SaveasTXTButton_2.Enable            = 'on';
            app.SaveasPNGButton_2.Enable            = 'on';
            app.VerifyButton.Enable		    = 'on';
            app.SaveasPNGButton.Enable 		    = 'on';
            app.VerifyButton.Enable		    = 'on';
            app.SaveasTXTButton.Enable		    = 'on';
            
            app.RunningLamp.Color = 'w';
        end
        
        function resultVoltage = Flow2Voltage(app, flow, valveDirection)
            x = flow;
            if (valveDirection == 0) % opening
                  
                %opening: V=f(flow)
                
 p1 =  0.000000000039788;
 p2 = -0.000000011912097;
 p3 =  0.000001286237565;
 p4 = -0.000052401476876;
 p5 = -0.000012780636578;
 p6 =  0.088219705515056;
 p7 =  2.323701291219307;
                        
                %f(x) = p1*x^6 + p2*x^5 + p3*x^4 + p4*x^3 + p5*x^2 + p6*x + p7
                resultVoltage = p1*x^6 + p2*x^5 + p3*x^4 + p4*x^3 + p5*x^2 + p6*x + p7;
                
            elseif (valveDirection == 1) %closing
                  
                %opening: V=f(flow)
                
 p1 = -0.000000000048219;
 p2 =  0.000000003834896;
 p3 =  0.000000576847930;
 p4 = -0.000066177181290;
 p5 =  0.002025808449995;
 p6 =  0.028743774527763;
 p7 =  1.605113021985799;
 
% New (to be implemented after Diego's tests)
% p1 =   0.000000003213130;
% p2 =  -0.000000537593646;
% p3 =   0.000022948116268;
% p4 =   0.000572654354636;
% p5 =  -0.063848612449939;
% p6 =   1.848531085559867;
% p7 =  -3.580649398773227;
                        
                %f(x) = p1*x^6 + p2*x^5 + p3*x^4 + p4*x^3 + p5*x^2 + p6*x + p7
                resultVoltage = p1*x^6 + p2*x^5 + p3*x^4 + p4*x^3 + p5*x^2 + p6*x + p7;
                
            else
                disp('error');
            end
            
        end
    end
    
    methods (Access = public)
        
        function NIpresent = CheckNIStatus(app)
            app.DeviceNameEditField.Enable = 'off';
            
            app.NISatusLamp.Color = 'w'; % checking
            NIpresent = 1; % initialize to 1 and modify later if a problem occurs
            
            NiDeviceFoundComponentsUpdate(app, 0);
            
            
            Message(1,1,0,['Searching for NI devices, it must be named ' app.DeviceNameEditField.Value ' '], 'UDEF', app.TestIDEditField.Value);
            try
                % if you get an error here then NI device is not connected OR
                % you don't have the drivers installed (NI daqmx + Matlab data aquisition toolbox)
                
                s = daq.createSession('ni');
                % A channel is added jsut to make sure the connection is established
                [~,~] = addAnalogOutputChannel(s,app.DeviceNameEditField.Value, 0, 'Voltage');
                % The channel is cleared so there is no inteferences
                removeChannel(s,1:length(s.Channels));
                clear('s');
                
                app.NISatusLamp.Color = 'g';
                
                NiDeviceFoundComponentsUpdate(app, 1); %enables widgets
                
                Message(1,1,0,['The NI device named ' app.DeviceNameEditField.Value ' has been found'], 'OK', app.TestIDEditField.Value);
                
            catch error
                app.NISatusLamp.Color   = 'r';
                % errordlg('No NI devices found','Device Error');
                
                Message(1,1,0,['Searching for NI devices, it must be named ' app.DeviceNameEditField.Value ' '], 'UDEF', app.TestIDEditField.Value);
                
                uialert(app.UIFigure,'NI device not found','Communication error');
                NIpresent = 0;
                app.DeviceNameEditField.Enable = 'on'; %enable the field if there are some problems
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %this test ID
            formatOut = 'yyyy-mm-dd--HH-MM-SS-FFF';
            app.TestIDEditField.Value = datestr(now,formatOut);
            
            Message(1,1,1,'Start of the log', 'UDEF', app.TestIDEditField.Value);
            
            %delete any old temp file
            if exist([cd '\RawOutput.mat'],'file') == 2
                delete([cd '\RawOutput.mat']);
                Message(1,1,0,'RawOutput.mat has been deleted', 'OK', app.TestIDEditField.Value);
            end
            
            %ajust the window to the screen
            app.UIFigure.Position = [165          52        1002         629];
            app.UIFigure.Resize = 'on';
            app.UIFigure.AutoResizeChildren = 'on';
            
            % cleaning
            app.StatusLamp.Color =  'k'; % starting
            app.RunningLamp.Color = 'w'; % not running
            app.NISatusLamp.Color = 'b'; % not checked yet
            
            %disable all non essential buttons
            %app.ChoosefileButton.Enable         = 'off';
            app.SaveasPNGButton.Enable          = 'off';
            app.SaveasTXTButton.Enable          = 'off';
            app.UnverifiedLabel.Enable          = 'off';
            app.GoButton.Enable                 = 'off';
            app.SaveasTXTButton_2.Enable        = 'off';
            app.SaveasMATButton_2.Enable        = 'off';
            app.SaveasPNGButton_2.Enable        = 'off';
            app.VerifyButton.Enable             = 'off';
            app.EditField.Enable                = 'off';
            app.SlopeVsButton.Enable            = 'off';
            app.TimesButton.Enable              = 'off';
            app.VoltageVEditField_2.Enable      = 'off';
            app.RampCheckBox.Enable             = 'off';
            
            
            %disabled until the NI device is detected
            app.FlowpositionDropDown.Enable     	= 'off';
            app.VoltageVSlider.Enable           	= 'off';
            app.FullcloseButton.Enable          	= 'off';
            app.FullopenButton.Enable           	= 'off';
            app.FullclosetofullopenButton.Enable	= 'off';
            app.PositiondegEditField_2.Enable 		= 'off';
            app.VoltageVEditField_2.Enable 		= 'off';
            app.Button_3.Enable                 	= 'off';
            app.ReadButton.Enable               	= 'off';
            app.GoButtonManual.Enable           	= 'off';
            app.CurrentpositiondegEditField.Enable	= 'off';
            app.CurrentvoltageVEditField.Enable 	= 'off';
            app.ChoosefileButton.Enable                 = 'off';
            
            
            %default label view
            app.UnverifiedLabel.Text = 'Unverified';
            
            %default for dropdown
            app.FlowpositionDropDown.Value = 'Flow';
            
            
            app.StatusLamp.Color = 'b'; % idle/waiting for input
            
            % Dialog to make sure user remembered to connect the NIUSB
            uialert(app.UIFigure,'Did you rememember to connect the NIUSB6211 BEFORE starting Matlab?','Pre-requisite','Icon','warning');
            
            % scan for NI devices
            Message(1,1,0,'Scanning for NI devices', 'UDEF', app.TestIDEditField.Value);
            d = daq.getDevices;
            errorgetDevices = 0; % init the flag to "no errors"
            if ~isempty(d)
                str = {d.ID};
                Message(1,1,0,'Some NI devices have been  found. Asking user to select which one to use', 'UDEF', app.TestIDEditField.Value);
                [selectedDev,oklist] = listdlg('PromptString','Select a file:',...
                    'SelectionMode','single',...
                    'ListString',str);
                
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                
                if oklist ~= 1
                    errorgetDevices = 1; % error
                    Message(1,1,0,'Error while user select in the list', 'KO', app.TestIDEditField);
                else
                    app.DeviceNameEditField.Value = d(selectedDev).ID;
                    NIpresent = CheckNIStatus(app);
                    app.DeviceNameEditField.Enable = 'off';
                end
            else
                errorgetDevices = 1; % set flag to show there has been an error while 
                Message(1,1,0,'No NI devices detected', 'KO', app.TestIDEditField.Value);
            end
            
            if errorgetDevices %if there is an error
%                 app.DeviceNameEditField.Value = ''; % empty the device name field 
                % errordlg('No NI devices registered','Device Error');
                app.DeviceNameEditField.Enable = 'on';
                Message(1,1,0,'No NI devices registered', 'KO', app.TestIDEditField);
                uialert(app.UIFigure,'No NI devices found, close Matlab, un- and re- connect the NI device','Device Error');
            end
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if strcmp(app.UnverifiedLabel.Text, 'Verified')
                
                DisableForMovement(app);
                app.ChoosefileButton.Enable = 'off';
                
                % erase the plot "Actual", even if there is nothing
                ax = app.UIAxes_2;
                cla(ax);
                
                % This section tries to to identify the file that is used to hold the "global variables"
                % of the test. If the file exists then open it and load the data (they consist of the verified
                % flow and time)
                try
                    if exist([cd '\RawOutput.mat'],'file') == 2
                        load([cd '\RawOutput.mat'],'completePosition', 'completeTime_cumsum', 'occured_error');
                    else
                        Message(1,1,0,'StartScenario file could not be found', 'KO', app.TestIDEditField);
                        ME = MException('StartScenario:noScenarioFile', ...
                            'File %s not found',[cd '\RawOutput.mat']);
                        throw(ME);
                    end
                    
                    
                    nbrSteps = size(completePosition,1);
                    nbrStage = nbrSteps - 1;
                    ErrorMarginVoltage = app.PositionTolerancedegEditField.Value; % [V]
                    
                    actualVoltage = zeros(nbrSteps,1);
                    
                    %--------------------------------------------------------------------------------------
                    %Initial position (go there as fast as possible)
                    MoveToVoltage(app, Position2Voltage(app,completePosition(1)));
                    %Read current position and verify error is within tolerance
                    cnt_overide = 0;
                    actualVoltage(1) = ReadVoltage(app);
                    targetVoltage = Position2Voltage(app,completePosition(1));
                    
                    while ((abs(actualVoltage(1) - targetVoltage) > ErrorMarginVoltage) && (cnt_overide < 5))
                        Message(1,1,0,['Valve target voltage : ' num2str(targetVoltage) ' Current voltage: ' num2str(actualVoltage(1))],...
                            'KO', app.TestIDEditField);
                        Message(1,1,0,['[Init] Voltage error : ' num2str(abs(actualVoltage(1) - targetVoltage)) ' limited to : ' num2str(ErrorMarginVoltage)],...
                            'KO', app.TestIDEditField);
                        if cnt_overide > 5
                            Message(1,1,0,['The valve is tacking too long to reach the target position - iteration: ' num2str(cnt_overide)'],...
                                'KO', app.TestIDEditField);
                        end
                        cnt_overide = cnt_overide + 1;
                        MoveToVoltage(app, Position2Voltage(app,completePosition(1))); % reissue the same command
                        actualVoltage(1) = ReadVoltage(app); % reading again
                    end
                    
                    if cnt_overide >= 10
                        uialert(app.UIFigure,'The valve feedback doesn''t match the command despite multiple attempts','Device Error');
                        Message(1,1,0,'The valve feedback doesn''t match the command despite multiple attempts, cancelling the run', 'KO', app.TestIDEditField);
                    else
                        
                        %--------------------------------------------------------------------------------------
                        %Calculate time intervals
                        TimeIntervals = diff(completeTime_cumsum);
                        
                        TTT = [0; diff(completeTime_cumsum)];
                        %                         TTT                     = TimeIntervals; %[0 5 6 8]';
                        pos                     = nan(length(completePosition), 1);
                        for cnt_pos = 1 : length(completePosition)
                            pos(cnt_pos,1) = Position2Voltage(app, completePosition(cnt_pos));
                        end
                        samples_per_sec         = app.NIRatesamplessEditField.Value;
                        min_samples_per_sec     = 2; %Hardcoded, imposed by the NI6211
                        required_buffer_size    = sum(TTT) * min_samples_per_sec;
                        samples_limit           = 4095; %4095; %Hardcoded, imposed by the NI6211
                        loaded_sample           = 100; %chosen to minimise the loading time
                        
                        min_sample_flag = 0; % initialise to: no problems
                        if required_buffer_size > samples_limit
                            min_sample_flag = 1;
                        end
                        
                        try
                            %---------------------------------------------------------------------------------
                            s = daq.createSession('ni');
                            % Add a analog input channel to read data from the valve
                            % AI
                            [ch,~] = addAnalogInputChannel(s,app.DeviceNameEditField.Value, 0, 'Voltage');
                            s.Channels.TerminalConfig = 'SingleEnded';
                            ch.Range = [-10,10];
                            if min_sample_flag
                                s.Rate = min_samples_per_sec;
                            else
                                s.Rate = app.NIRatesamplessEditField.Value;
                            end
                            s.TriggersPerRun = 1;
                            
                            % Add a analog output to send the orders to the valve
                            % AO
                            [ch,~] = addAnalogOutputChannel(s,app.DeviceNameEditField.Value, 0, 'Voltage');
                            ch.Range = [-10,10];% Set a range of -10 to +10 volts. if not sure: ch.RangesAvailable
                            if min_sample_flag
                                s.Rate = min_samples_per_sec;
                            else
                                s.Rate = app.NIRatesamplessEditField.Value;
                            end
                            s.TriggersPerRun = 1;
                            
                            %---------------------------------------------------------------------------------
                            if min_sample_flag
                                Message(1,1,0,...
                                    ['The length of the scenario is greater than the NI USB buffer,' ...
                                    'even at the minimum sampling frequency: ' num2str(min_samples_per_sec) ' Hz'],...
                                    'KO', app.TestIDEditField);
                                Message(1,1,0,['Data will be loaded by sets of ' num2str(loaded_sample)...
                                    ' samples at a time for the entire duration of the'...
                                    ' scenario and the sampling rate will be adjusted to the'...
                                    ' minimum: ' num2str(loaded_sample) ' S/s'], 'KO', app.TestIDEditField);
                                app.NIRatesamplessEditField.Value = 2;
                                app.NIRatesamplessEditField.Enable = 'off'; %lock the change
                                %create matrix
                                nbr_columns = ceil(required_buffer_size/loaded_sample);
                                %test_matrix = zeros(loaded_sample, nbr_columns)
                                overall = nan(required_buffer_size,1); %init
                                indx_prev = 1;
                                for cnt_step = 1 : length(pos)-1
                                    if TTT(cnt_step+1) ~= 0
                                        nbr_samples_epoch = TTT(cnt_step+1)*min_samples_per_sec;
                                        Message(1,1,0, ['Loading values to main array from ' num2str(indx_prev) ...
                                            ' to ' num2str(indx_prev + nbr_samples_epoch - 1)], 'UDEF', app.TestIDEditField); 
                                        overall(indx_prev:indx_prev + nbr_samples_epoch - 1,1) =...
                                            linspace(pos(cnt_step),pos(cnt_step+1), nbr_samples_epoch);
                                        indx_prev = indx_prev + nbr_samples_epoch;
                                    end
                                end
                                
                                % Limit the command to [0, 10V], which translates to [0, 90deg]
                                overall = min(10*ones(length(overall),1), overall);
                                overall = max(00*ones(length(overall),1), overall);
                                
                                CommandOutputQueue = reshape(overall(1:floor(required_buffer_size/loaded_sample)*loaded_sample,1),...
                                    loaded_sample, nbr_columns - ( mod(required_buffer_size, loaded_sample) ~=0 ));
                                
                                %deal with leftovers
                                non_null_leftover = (overall(floor(required_buffer_size/loaded_sample)*loaded_sample + 1 : required_buffer_size));
                                null_leftover = ones(loaded_sample * (mod(required_buffer_size, loaded_sample) ~= 0) - length(non_null_leftover), 1) .* overall(end);
%                                 null_leftover = ones(loaded_sample - length(non_null_leftover), 1) .* overall(end);
                                Message(1,1,0, ['Last-value-padding of ' num2str(length(null_leftover)) ' values'], 'UDEF', app.TestIDEditField); 
                                clearvars overall
                                %concatenate last column
                                CommandOutputQueue = [CommandOutputQueue, [non_null_leftover; null_leftover]];
                                
                                
                            else % the size of the test can fit in 1 go in the NIUSB buffer
                                
                                % Create and queue an output signal: direct, hard, step to target
                                CommandOutputQueue = nan(completeTime_cumsum(end) * s.Rate,1);
                                stopindx = (completeTime_cumsum(2:end)) .* s.Rate; % cumsum starts @ 0 so offset by 1 to get the indexes for Matlab
                                strtindx = [1; (stopindx(1:end-1))+1];
                                
                                for cnt_pos = 1 : nbrStage % a stage can  be a ramp or a hold
                                    CommandOutputQueue(strtindx(cnt_pos):stopindx(cnt_pos)) = ...
                                        linspace(...
                                        Position2Voltage(app, completePosition(cnt_pos)),...
                                        Position2Voltage(app, completePosition(cnt_pos+1)),...
                                        TimeIntervals(cnt_pos) * s.Rate)';
                                end
                                
                                % Limit the command to [0, 10V], which translates to [0 90deg]
                                CommandOutputQueue = min(10*ones(length(CommandOutputQueue),1), CommandOutputQueue);
                                CommandOutputQueue = max(00*ones(length(CommandOutputQueue),1), CommandOutputQueue);
                            end
                            
                            
                            %---------------------------------------------------------------------------------
                            % Indicate motion
                            app.RunningLamp.Color = 'r';
                            
                            if min_sample_flag % we need multiple loadings or "queueOutputData"
                                
                                %initialise variables
                                TotalTimeSpentLoading = 0; % [s]
                                
                                % Load data column by column
                                %s.Rate = min_samples_per_sec; % make sure the NIDaq is set up for the correct rate
                                acquiredData = nan(size(CommandOutputQueue,1), size(CommandOutputQueue,2));
 
                                for cnt_loads = 1 : size(CommandOutputQueue,2)
                                    if (app.STOPButton.Value == 1) % if the button is pressed then stop
                                        break
                                    end
                                    
                                    tic
                                    queueOutputData(s, CommandOutputQueue(:, cnt_loads)); %This queuing needs to as fast as possible
                                    %acquiredData(:,cnt_loads) = startForeground(s); % blocking call to read and write at the same time
                                    
                                    acquiredData_temp = startForeground(s);
                                    %optimise
                                    acquiredData(:, cnt_loads) = acquiredData_temp;
                                    clearvars acquiredData_temp
                                    %s.StartForground(); % wait here until all the samples have been sent (blocking call)
                                    
                                    
                                    % make sure final position is really
                                    % reached
                                    queueOutputData(s,CommandOutputQueue(end, cnt_loads));
                                    % Send signal and wait until finished
                                    startForeground(s);
                                    %Read current position and verify error is within tolerance
                                    cnt_overide = 0;
                                    queueOutputData(s,CommandOutputQueue(end, cnt_loads));
                                    [data,~] = s.startForeground;
                                    actualVoltage = mean(data); %average of the input
                                    targetVoltage = CommandOutputQueue(end, cnt_loads);
                                    
                                    while ((abs(actualVoltage(1) - targetVoltage) > ErrorMarginVoltage) && (cnt_overide < 5))
                                        Message(1,1,0,['Valve target voltage : ' num2str(targetVoltage) ' Current voltage: ' num2str(actualVoltage(1))],...
                                            'KO', app.TestIDEditField);
                                        Message(1,1,0,['[Scenario] Voltage error : ' num2str(abs(actualVoltage(1) - targetVoltage)) ' limited to : ' num2str(ErrorMarginVoltage)],...
                                            'KO', app.TestIDEditField);
                                        if cnt_overide > 5
                                            Message(1,1,0,['The valve is tacking too long to reach the target position - iteration: ' num2str(cnt_overide)'],...
                                                'KO', app.TestIDEditField);
                                        end
                                        cnt_overide = cnt_overide + 1;
                                        % reissue the same command
                                        queueOutputData(s,CommandOutputQueue(end, cnt_loads));
                                        % Send signal and wait until finished
                                        startForeground(s);
                                        % reading again
                                        queueOutputData(s,CommandOutputQueue(end, cnt_loads));
                                        [data,~] = s.startForeground;
                                        actualVoltage = mean(data); %average of the input
                                    end
                                    
                                    if cnt_overide >= 5
                                        Message(1,1,0,'The valve feedback doesn''t match the command despite multiple attempts, continuing the run', 'KO', app.TestIDEditField);
                                    end
                                    
                                    
                                    % display time spent loading the data (100 samples @ 2Hz is 50s)
                                    TimeSpentLoading = 50-toc;
                                    %TimeSpentLoading = toc;
                                    TotalTimeSpentLoading = TotalTimeSpentLoading + TimeSpentLoading;
                                    Message(1,1,0, ['Time spent loading the data for iteration ' num2str(cnt_loads) '/' num2str(size(CommandOutputQueue,2))...
                                        ' is ' num2str(TimeSpentLoading) ' [s], total estimated is: '...
                                        num2str(TimeSpentLoading * size(CommandOutputQueue,2)) ' [s]'],...
                                        'UDEF', app.TestIDEditField);
                                    Message(1,1,0, ['Current angle [°]: ' num2str(Voltage2Position(app,acquiredData(end, cnt_loads))) ' at ' num2str(TimeSpentLoading)],...
                                        'UDEF', app.TestIDEditField);
                                
                                    % save data
                                    RunID = app.TestIDEditField.Value;
                                    try
                                        if ~exist([cd '\Data'],'dir')
                                            mkdir([cd '\Data']);
                                        end
                                        if ~exist([cd '\Data\Test-' RunID],'dir')
                                            mkdir([cd '\Data\Test-' RunID]);
                                        end
                                        SaveSensorDataFileName = [cd '\Data\Test-' RunID '\DirectReadNIUSB.mat'];
                                        save(SaveSensorDataFileName,'acquiredData');
                                    catch error
                                        disp(error);
                                        occured_error = 1;
                                        Message(1,1,0,'Data couldn''t be saved', 'KO', RunID);
                                    end
                                
                                end
                                
                                %reshape the data captured by the NI-USB
                                 acquiredData = reshape(acquiredData, 100 * size(CommandOutputQueue,2), 1);
                                CommandOutputQueue = reshape(CommandOutputQueue, 100 * size(CommandOutputQueue,2), 1);
                                
                            else % we can send the signal in 1 go
                                
                                % Used to send the profile as one
                                queueOutputData(s,CommandOutputQueue);
                                
                                % Send signal to NI, !!blocking call!!, and wait until it finishes
                                acquiredData = startForeground(s); % blocking call to read AND write at the same time
                            end


                            % Plot data
                            plot(app.UIAxes_2,(1:length(acquiredData))./s.Rate,Voltage2Position(app,CommandOutputQueue),'b-');
                            hold(app.UIAxes_2,'on');
                            plot(app.UIAxes_2,(1:length(acquiredData))./s.Rate,Voltage2Position(app,acquiredData),'r-');
                            hold(app.UIAxes_2,'off');
                            app.UIAxes_2.XLim = [-1 (length(acquiredData) ./ s.Rate + 5)];
                            app.UIAxes_2.YLim = [-1 (5+Voltage2Position(app,max( [max(CommandOutputQueue) max(acquiredData)])))];
                            
                            % Clean up
                            removeChannel(s,1:length(s.Channels));
                            clear('s');
                            
                        catch e
                            % Error handling
                            disp(e);
                            Message(1,1,0,'Error while GoButtonPushed', 'KO', app.TestIDEditField);
                            if strcmp(e.identifier, 'daq:Session:noDevicesAvailable')
                                %                             errordlg('NI device not found','Communication error');
                                
                                uialert(app.UIFigure,'NI device not found','Communication error');
                                app.UIFigure.Visible = 'off';
                                app.UIFigure.Visible = 'on';
                            elseif strcmp(e.identifier,'daq:general:unknownVendor')
                                %                             errordlg('NI packages not found','Package error');
                                
                                uialert(app.UIFigure,'NI packages not found','Package error');
                                app.UIFigure.Visible = 'off';
                                app.UIFigure.Visible = 'on';
                            end
                        end
                    end
                catch e
                    app.StatusLamp.Color = 'r';
                    % Error handling
                    disp(e);
                    Message(1,1,0,'Error while GoButtonPushed - ext', 'KO', app.TestIDEditField);
                    
                end
                app.NIRatesamplessEditField.Enable = 'on';
                %app.NIRatesamplessEditField.Value = samples_per_sec;
                
                EnableAfterMovement(app);
                app.ChoosefileButton.Enable = 'on';
            end
        end

        % Value changed function: FlowpositionDropDown
        function FlowpositionDropDownValueChanged(app, event)
            value = app.FlowpositionDropDown.Value;
            %app.ChoosefileButton.Enable = 'off';
            
            %Reset the 'verified' status and the associated enabled controls
            %Reset the status of the verification of the table
            cla(app.UIAxes);
            app.UITable.Data = [];
            app.UnverifiedLabel.Text = 'Unverified';
            app.GoButton.Enable = 'off';
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            
            switch value
                case 'Flow'
                    % modify the title of the 1st graph
                    app.UIAxes.Title.String         = 'Target Flow';
                    app.UIAxes.YLabel.String        = 'Flow [l/s]';
                    % modify the name of the column
                    app.UITable.ColumnName{2}       = 'Target Flow [l/s]';


                case 'Position'
                    % modify the title of the 1st graph
                    app.UIAxes.Title.String = 'Target Position';
                    app.UIAxes.YLabel.String = 'Position [deg]';
                    % modify the name of the column
                    app.UITable.ColumnName{2}  = 'Target Position [deg]';
                    
            end
            %app.ChoosefileButton.Enable = 'on';
            
            %Change lamp status
            app.StatusLamp.Color = 'w'; % idle
            
        end

        % Value changed function: VoltageVSlider
        function VoltageVSliderValueChanged(app, event)
            value = app.VoltageVSlider.Value;
            % When the voltage slider has been modified, the following widgets are updated:

            %Voltage edit field
            %check it is in the limits of the field
            if (value >= app.VoltageVEditField_2.Limits(1) && value <= app.VoltageVEditField_2.Limits(end))
                app.VoltageVEditField_2.Value = value;
            else
                app.VoltageVEditField_2.Value = app.VoltageVEditField_2.Limits(1);
            end
            %Angular position edit field
            value_position = Voltage2Position(app, value);
            %check it is in the limits of the field
            if (value_position >= app.PositiondegEditField_2.Limits(1) && value_position <= app.PositiondegEditField_2.Limits(end))
                app.PositiondegEditField_2.Value = value_position;
            else
                app.PositiondegEditField_2.Value = app.PositiondegEditField_2.Limits(1);
            end
        end

        % Value changed function: PositiondegEditField_2
        function PositiondegEditField_2ValueChanged(app, event)
            value = app.PositiondegEditField_2.Value;
            % When the field of the position has been modified, the following widgets are updated:
            
            %Voltage slider
            app.VoltageVSlider.Value = Position2Voltage(app, value);
            %Voltage edit field
            app.VoltageVEditField_2.Value = Position2Voltage(app, value);
            
            
            % When the angular position field has been modified, the following widgets are updated:
            valueVoltage = Position2Voltage(app, value);
            %Voltage edit field
            %check it is in the limits of the field
            if (valueVoltage >= app.VoltageVSlider.Limits(1) && valueVoltage <= app.VoltageVSlider.Limits(end))
                app.VoltageVSlider.Value = valueVoltage;
            else
                app.VoltageVSlider.Value = app.VoltageVSlider.Limits(1);
            end
            %Angular position edit field
            %check it is in the limits of the field
            if (valueVoltage >= app.VoltageVEditField_2.Limits(1) && valueVoltage <= app.VoltageVEditField_2.Limits(end))
                app.VoltageVEditField_2.Value = valueVoltage;
            else
                app.VoltageVEditField_2.Value = app.VoltageVEditField_2.Limits(1);
            end
        end

        % Button pushed function: GoButtonManual
        function GoButtonManualPushed(app, event)
            % disable the GoButtonManual button to avoid multiple call and bloc the NI device
            DisableForMovement(app)
            
            if app.RampCheckBox.Value % The ramp option has been selected by the user
                selectedButton = app.RampTypeButtonGroup.SelectedObject;
                rampValue       = min([max([0, app.EditField.Value]) 10]);
                startingVoltage = min([max([0, ReadVoltage(app)]) 10]);
%                 startingVoltage = ReadVoltage(app);
                switch selectedButton.Text
                    case 'Slope [V/s]' %slope V/s
                        rampTime = abs(app.VoltageVSlider.Value-startingVoltage)/rampValue; %in s
                    case 'Time [s]' % time s
                        rampTime = rampValue;
                end
                Message(1,1,0,['The ramp time is ' num2str(rampTime) ' s'], 'UDEF', app.TestIDEditField);
                if rampTime > 30
                    Message(1,1,0,['The ramp time would be ' num2str(rampTime) 's which is too long, limitating to 30s'], 'KO', app.TestIDEditField);
                    rampTime = 30;
                end
                MoveToVoltagewithTime(app, startingVoltage, app.VoltageVSlider.Value, rampTime);
            else % single voltage
                MoveToVoltage(app, app.VoltageVSlider.Value);
            end
            
            sinleManualRead(app);
            
            EnableAfterMovement(app);
        end

        % Button pushed function: FullcloseButton
        function FullcloseButtonPushed(app, event)
            DisableForMovement(app);
            MoveToVoltage(app, 0);
            EnableAfterMovement(app);
        end

        % Button pushed function: FullopenButton
        function FullopenButtonPushed(app, event)
            DisableForMovement(app)
            MoveToVoltage(app, 10);
            EnableAfterMovement(app);
        end

        % Callback function
        function FullclosetofullopenButtonPushed(app, event)
            %  direct to 0V, wait pos reached, hard to 10V, wait pos reached
            DisableForMovement(app)
            MoveToVoltage(app, 0);
            MoveToVoltage(app, 10);
            EnableAfterMovement(app);
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            
            %Reset the status of the verification of the table
            cla(app.UIAxes);
            app.UITable.Data = [];
            app.UnverifiedLabel.Text = 'Unverified';
            app.GoButton.Enable = 'off';
            
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            
            try
                [file,path] = uigetfile('*.xlsx');
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                if isequal(file,0)
                    Message(1,1,0,'The user asked to cancel', 'UDEF', app.TestIDEditField);
                    %                 occured_error = 1;
                    app.StatusLamp.Color = 'b';
                else
                    Message(1,1,0,['The user selected: ', fullfile(path,file)], 'UDEF', app.TestIDEditField);
                    filename = fullfile(path,file);
                    app.StatusLamp.Color = 'g';
                    
                    % Process the file
                    system('taskkill /F /IM EXCEL.EXE'); % kill excel process otherwise you will have wierd errors
                    num = xlsread(filename);
                    
                    %Check if this is numbers
                    if isnumeric(num)
                        if ~isempty(num)
                            if size(num,2) == 3
                                if ~any(any(isnan(num)))
                                    %Create the "index array in the table"
                                    app.UITable.Data = [(1:size(num,1))' , num];
                                    app.UITable.RowStriping ='on';
                                    
                                    %Enable the verify button
                                    app.VerifyButton.Enable = 'on';
                                else
                                    Message(1,1,0,'The XLSX file contains some values identified as NaN', 'KO', app.TestIDEditField);
                                    errorStruct.message = 'The XLSX file contains some values identified as NaN';
                                    errorStruct.identifier = 'ChoosefileButtonPushed:WrongDataIn';
                                    error(errorStruct); % throw an error to get caught by the global try/catch
                                end
                            else
                                Message(1,1,0,['The XLSX file has' numsstr(size(num,2)) ' columns but should have 3'], 'KO', app.TestIDEditField);
                                errorStruct.message = ['The XLSX file has' numsstr(size(num,2)) ' columns but should have 3'];
                                errorStruct.identifier = 'ChoosefileButtonPushed:WrongDataIn';
                                error(errorStruct); % throw an error to get caught by the global try/catch
                            end
                        else
                            Message(1,1,0,'The XLSX file contains no values', 'KO', app.TestIDEditField);
                            errorStruct.message = 'The XLSX file contains no values';
                            errorStruct.identifier = 'ChoosefileButtonPushed:WrongDataIn';
                            error(errorStruct); % throw an error to get caught by the global try/catch
                        end
                    else
                        Message(1,1,0,'The XLSX file contains non numeric values', 'KO', app.TestIDEditField);
                        errorStruct.message = 'The XLSX file contains non numeric values';
                        errorStruct.identifier = 'ChoosefileButtonPushed:WrongDataIn';
                        error(errorStruct); % throw an error to get caught by the global try/catch
                    end
                end
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
            catch error
                Message(1,1,0,'Something went wrong with the file', 'KO', app.TestIDEditField);
                app.StatusLamp.Color = 'r';
                %             occured_error = 1;
            end
            
            
        end

        % Button pushed function: SaveasTXTButton
        function SaveasTXTButtonPushed(app, event)
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            RunID = app.TestIDEditField.Value;
            data = app.UITable.Data;
            occured_error = 0;
            try
                if ~exist([cd '\Data'],'dir')
                    mkdir([cd '\Data']);
                end
                if ~exist([cd '\Data\Test-' RunID],'dir')
                    mkdir([cd '\Data\Test-' RunID]);
                end
                SaveSensorDataFileName = [cd '\Data\Test-' RunID '\ValveOpeningScenario.txt'];
                %                 fid = fopen(SaveSensorDataFileName,'wt');
                %                 fprintf(fid,'%.2f\n',data);
                %                 fclose(fid);
                dlmwrite(SaveSensorDataFileName,data,'\t');
            catch error
                disp(error);
                occured_error = 1;
                Message(1,1,0,'Data couldn''t be saved', 'KO', RunID);
            end
            
            if ~occured_error
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
            else
                %Change lamp status
                app.StatusLamp.Color = 'r'; % error
            end
        end

        % Callback function
        function EmergencycloseButtonValueChanged(app, event)
            value = app.EmergencycloseButton.Value;
            
            switch value
                case 1
                    %Change lamp status
                    app.StatusLamp.Color = 'r';
                    app.RunningLamp.Color = 'w';
                    
                    %Disable all other buttons, the only way to get out of this mode is to push the emergency button again
                    app.ChoosefileButton.Enable = 'off';
                    app.SaveasPNGButton.Enable = 'off';
                    app.SaveasTXTButton.Enable = 'off';
                    app.UnverifiedLabel.Enable = 'off';
                    app.GoButton.Enable = 'off';
                    app.SaveasTXTButton_2.Enable = 'off';
                    app.SaveasMATButton_2.Enable = 'off';
                    app.SaveasPNGButton_2.Enable = 'off';
                    app.VerifyButton.Enable = 'off';
                    
                    app.UnverifiedLabel.Text = 'Unverified';
                    
                    %send order to shut off to the valve
                    MoveToVoltage(app, 0); % will stay in that function until valve is shut
                    
                case 0
                    %Change lamp status
                    app.StatusLamp.Color = 'b'; % idle
                    
            end
            
        end

        % Button pushed function: NewIDButton
        function NewIDButtonPushed(app, event)
            formatOut = 'yyyy-mm-dd--HH-MM-SS-FFF';
            app.TestIDEditField.Value = datestr(now,formatOut);
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            
            formatOut = 'yyyy-mm-dd--HH-MM-SS-FFF';
            app.TestIDEditField.Value = datestr(now,formatOut);
            
            RunID = app.TestIDEditField.Value;
            occured_error = 0;
            
            DisableForMovement(app);
            
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            
            %clear the (2) figures
            ax = app.UIAxes;
            cla(ax);
            ax = app.UIAxes_2;
            cla(ax);
            
            %Check the data for consistency
            num = app.UITable.Data;
            
            
            %disable some buttons
            app.VerifyButton.Enable = 'off';
            
            % temporary put the status to good, if any bad it will be changed
            app.UnverifiedLabel.Text = 'Verified';
            
            try
                % temp_num is a VOLTAGE
                if strcmp(app.FlowpositionDropDown.Value,'Flow')
                    %temp_num  = Flow2Position(app, num(:,2));
                    %temp_num(1) = max([temp_num(1) 18]);
                    %temp_num = Position2Voltage(app, temp_num);
                    
                    %resultVoltage = Flow2Voltage(app, flow, valveDirection)
                    temp_num = zeros(size(num,1),1); 
                    rinsing_falling = diff(num(:,2))<=0;
                    for cnt_pos = 2 : size(num,1)
                        temp_num(cnt_pos) = Flow2Voltage(app, num(cnt_pos,2), rinsing_falling(cnt_pos - 1)); %0 is for opening, 1 is for closing
                    end
                    temp_num(1) = max([temp_num(1) 2]); %Start the valve at 18 deg
                    
                elseif strcmp(app.FlowpositionDropDown.Value,'Position')
                    num(1,2) = max([num(1,2) 18]);
                    temp_num = Position2Voltage(app, num(:,2));
                end
                
                % Just for user information (start/finish not zero)
                if temp_num(1) > 18/9 %(should be less than 18deg but allow for some conversion errors)
                    uialert(app.UIFigure, 'Starting position command should be less than 18deg, be careful','Warning',...
                        'Icon','warning');
                end
                
                if temp_num(end) > 1 %(should be 0 but allow for some conversion errors)
                    uialert(app.UIFigure, 'Last position command is not 0, be careful','Warning',...
                        'Icon','warning');
                end
                
                
                %check position
                indx = find(temp_num < 0);
                if ~ isempty(indx)
                    Message(1,1,0,'1 or more POSITION is negative', 'KO', RunID);
                    app.UnverifiedLabel.Text = 'Error(s)';
                    occured_error = 1;
                end
                
                indx = find(temp_num > 10);
                if ~ isempty(indx)
                    Message(1,1,0,'1 or more POSITION is > 90', 'KO', RunID);
                    app.UnverifiedLabel.Text = 'Error(s)';
                    occured_error = 1;
                end
                
                
                %check slope
                indx = find(abs(diff(temp_num)./num(2:end,3)) > 1/(app.ValveSpeedsVEditField.Value));
                if ~ isempty(indx)
                    Message(1,1,0,'1 or more slope is too small', 'KO', RunID);
                    app.UnverifiedLabel.Text = 'Error(s)';
                    occured_error = 1;
                end
                
                %check for negative TTT
                indx = find(num(:,3)<0);
                if ~ isempty(indx)
                    Message(1,1,0,'1 or more Time To Target is negative', 'KO', RunID);
                    app.UnverifiedLabel.Text = 'Error(s)';
                    occured_error = 1;
                end
                
                %check hold time
                indx = find(num(:,4)<0);
                if ~ isempty(indx)
                    Message(1,1,0,'1 or more HOLD TIME  is negative', 'KO', RunID);
                    app.UnverifiedLabel.Text = 'Error(s)';
                    occured_error = 1;
                end
                
                % Display the scenario time (sum of all Time To Target)
                uialert(app.UIFigure, ['The total scenario time is ' num2str(sum(num(:,3)))...
                    's. The number of samples is: ' num2str(sum(num(:,3))*app.NIRatesamplessEditField.Value)],'info',...
                    'Icon','info');
                
            catch
                occured_error = 1;
            end
            
            if ~occured_error
                completeTime = zeros((size(num,1)-1)*2,1); %2*x-3
                completePosition = zeros((size(num,1)-1)*2,1);
                
                for cnt = 1 : size(num,1)-1
                    completeTime(2*cnt-1) = num(cnt+1,3);
                    completeTime(2*cnt) = num(cnt+1,4);
                end
                
                %completeTime(end) = num(end,3);
                completeTime_cumsum = [0; cumsum(completeTime)];
                
                completePosition(1) = num(1,2);
                for cnt = 1 : size(num,1)-1
                    completePosition(2*cnt:2*cnt+1) =  num(cnt+1,2);
                end
                
                
                %Plot
                plot(app.UIAxes,completeTime_cumsum,completePosition,'-+');
                app.UIAxes.XLim = [-1 (completeTime_cumsum(end) + 5)];
                app.UIAxes.YLim = [-1 (max(completePosition)+1)];
                
                
                %always save the data as mat file
                save([cd '\RawOutput.mat'],'completePosition', 'completeTime_cumsum', 'occured_error');
                
                app.GoButton.Enable         = 'on';
                app.SaveasTXTButton.Enable  = 'on';
                app.SaveasPNGButton.Enable  = 'on';
                
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
                
            else
                
                uialert(app.UIFigure, '1 or more problems have been detected when parsing the Excel file','Warning',...
                    'Icon','warning');
                
                %Change lamp status
                app.StatusLamp.Color = 'r'; % problem
                app.UnverifiedLabel.Text = 'Error';
                app.GoButton.Enable = 'off'; % just to make sure
            end
            
            EnableAfterMovement(app);
            
            %re-enable some buttons
            app.VerifyButton.Enable = 'on';
        end

        % Button pushed function: SaveasPNGButton
        function SaveasPNGButtonPushed(app, event)
            
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            RunID = app.TestIDEditField.Value;
            data = app.UITable.Data;
            occured_error = 0;
            try
                if ~exist([cd '\Data'],'dir')
                    mkdir([cd '\Data']);
                end
                if ~exist([cd '\Data\Test-' RunID],'dir')
                    mkdir([cd '\Data\Test-' RunID]);
                end
                
                h = figure;
                h.Visible = 'off';
                x = app.UIAxes.XAxis.Parent.Children.XData;
                y = app.UIAxes.XAxis.Parent.Children.YData;
                plot(x,y)
                %lgndName1 = app.UIAxes.Legend.String{1};
                %lgd = legend(lgndName1);
                %lgd.Box = app.UIAxes.Legend.Box;
                %lgd.Location = app.UIAxes.Legend.Location;
                h.CurrentAxes.YLabel.String = app.UIAxes.YLabel.String;
                h.CurrentAxes.YLabel.FontSize = app.UIAxes.YLabel.FontSize;
                h.CurrentAxes.XLabel.String = app.UIAxes.XLabel.String;
                h.CurrentAxes.XLabel.FontSize = app.UIAxes.XLabel.FontSize;
                h.CurrentAxes.Title.String = app.UIAxes.Title.String;
                h.CurrentAxes.Title.FontSize = app.UIAxes.Title.FontSize;
                h.CurrentAxes.XLim = [0 max(x)];
                h.CurrentAxes.YLim = [0 max(y)+1];


                SaveSensorDataFileName = [cd '\Data\Test-' RunID '\ValveOpeningScenario.png'];
                print(h ,'-dpng', SaveSensorDataFileName,'-r600');
                delete(h);
            catch error
                disp(error);
                occured_error = 1;
                Message(1,1,0,'Graph couldn''t be saved', 'KO', RunID);
            end
            
            if ~occured_error
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
            else
                %Change lamp status
                app.StatusLamp.Color = 'r'; % error
            end
            
            
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            DisableForMovement(app)
            sinleManualRead(app);
            EnableAfterMovement(app);
        end

        % Button pushed function: CheckstatusButton
        function CheckstatusButtonPushed(app, event)
            CheckNIStatus(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist([cd '\RawOutput.mat'],'file') == 2
                delete([cd '\RawOutput.mat']);
            end
            
            delete(app) % what is that?
            
        end

        % Value changed function: RampCheckBox
        function RampCheckBoxValueChanged(app, event)
            value = app.RampCheckBox.Value;
            
            switch value
                case 0 % No ramp
                    app.EditField.Enable = 'off';
                    app.SlopeVsButton.Enable = 'off';
                    app.TimesButton.Enable = 'off';
                case 1 %  Ramp but which type?
                    app.EditField.Enable = 'on';
                    app.SlopeVsButton.Enable = 'on';
                    app.TimesButton.Enable = 'on';
            end
        end

        % Selection changed function: RampTypeButtonGroup
        function RampTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.RampTypeButtonGroup.SelectedObject;
            app.EditField.Value = 1e-3;
            switch selectedButton.Text
                case 'Slope [V/s]' %slope V/s
                    app.EditField.Limits = [1e-3 1/app.ValveSpeedsVEditField.Value];
                    app.RampParameterLabel.Text = 'Slope [V/s]';
                case 'Time [s]' % time s
                    app.EditField.Limits = [1e-3 10];
                    app.RampParameterLabel.Text  = 'Time [s]';
            end
        end

        % Value changed function: VoltageVEditField
        function VoltageVEditFieldValueChanged(app, event)
            value = app.VoltageVEditField.Value;
            
            %change lamp to busy
            app.StatusLamp.Color = 'g';
            %calculate and write to the position field
            app.PositiondegEditField.Value = Voltage2Position(app,value);
            %calculate and write to the flow field
            app.FlowlsEditField.Value =  Position2Flow(app, Voltage2Position(app,value));
            %change lamp to idle
            app.StatusLamp.Color = 'b';
            
        end

        % Value changed function: PositiondegEditField
        function PositiondegEditFieldValueChanged(app, event)
            value = app.PositiondegEditField.Value;
            
            %change lamp to busy
            app.StatusLamp.Color = 'g';
            %calculate and write to the voltage field
            app.VoltageVEditField.Value = Position2Voltage(app,value);
            %calculate and write to the flow field
            app.FlowlsEditField.Value =   Position2Flow(app,value);
            %change lamp to idle
            app.StatusLamp.Color = 'b';
            
        end

        % Value changed function: FlowlsEditField
        function FlowlsEditFieldValueChanged(app, event)
            value = app.FlowlsEditField.Value;
            
            %change lamp to busy
            app.StatusLamp.Color = 'g';
            %calculate and write to the voltage field
            app.VoltageVEditField.Value = Position2Voltage(app,Flow2Position(app,value));
            %calculate and write to the position field
            app.PositiondegEditField.Value =  Flow2Position(app, value);
            %change lamp to idle
            app.StatusLamp.Color = 'b';
            
        end

        % Button pushed function: SaveasTXTButton_2
        function SaveasTXTButton_2Pushed(app, event)
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            RunID = app.TestIDEditField.Value;
            data = [app.UIAxes_2.Children(1).XData',app.UIAxes_2.Children(1).YData', app.UIAxes_2.Children(2).YData'];
            occured_error = 0;
            try
                if ~exist([cd '\Data'],'dir')
                    mkdir([cd '\Data']);
                end
                if ~exist([cd '\Data\Test-' RunID],'dir')
                    mkdir([cd '\Data\Test-' RunID]);
                end
                SaveSensorDataFileName = [cd '\Data\Test-' RunID '\ValveOpeningReal.txt'];
                %                 fid = fopen(SaveSensorDataFileName,'wt');
                %                 fprintf(fid,'%.2f\n',data);
                %                 fclose(fid);
                dlmwrite(SaveSensorDataFileName,data,'\t');
            catch error
                disp(error);
                occured_error = 1;
                Message(1,1,0,'Data couldn''t be saved', 'KO', RunID);
            end
            
            if ~occured_error
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
            else
                %Change lamp status
                app.StatusLamp.Color = 'r'; % error
            end
        end

        % Button pushed function: SaveasPNGButton_2
        function SaveasPNGButton_2Pushed(app, event)
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            RunID = app.TestIDEditField.Value;
            data = app.UITable.Data;
            occured_error = 0;
            try
                if ~exist([cd '\Data'],'dir')
                    mkdir([cd '\Data']);
                end
                if ~exist([cd '\Data\Test-' RunID],'dir')
                    mkdir([cd '\Data\Test-' RunID]);
                end
                
                h = figure;
                h.Visible = 'off';
                x = app.UIAxes_2.XAxis.Parent.Children(1).XData;
                y1 = app.UIAxes_2.XAxis.Parent.Children(1).YData;
                y2 = app.UIAxes_2.XAxis.Parent.Children(2).YData;
                plot(x,y1);
                hold on;
                plot(x,y2);
                hold off;
                %lgndName1 = app.UIAxes.Legend.String{1};
                %lgd = legend(lgndName1);
                %lgd.Box = app.UIAxes.Legend.Box;
                %lgd.Location = app.UIAxes.Legend.Location;
                h.CurrentAxes.YLabel.String = app.UIAxes_2.YLabel.String;
                h.CurrentAxes.YLabel.FontSize = app.UIAxes_2.YLabel.FontSize;
                h.CurrentAxes.XLabel.String = app.UIAxes_2.XLabel.String;
                h.CurrentAxes.XLabel.FontSize = app.UIAxes_2.XLabel.FontSize;
                h.CurrentAxes.Title.String = app.UIAxes_2.Title.String;
                h.CurrentAxes.Title.FontSize = app.UIAxes_2.Title.FontSize;
                h.CurrentAxes.XLim = [0 max(x)];
                h.CurrentAxes.YLim = [0 max([max(y1), max(y2)])+1];


                SaveSensorDataFileName = [cd '\Data\Test-' RunID '\ValveOpeningReal.png'];
                print(h ,'-dpng', SaveSensorDataFileName,'-r600');
                delete(h);
            catch error
                disp(error);
                occured_error = 1;
                Message(1,1,0,'Graph couldn''t be saved', 'KO', RunID);
            end
            
            if ~occured_error
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
            else
                %Change lamp status
                app.StatusLamp.Color = 'r'; % error
            end
        end

        % Button pushed function: SaveasMATButton_2
        function SaveasMATButton_2Pushed(app, event)
            %Change lamp status
            app.StatusLamp.Color = 'g'; % working
            RunID = app.TestIDEditField.Value;
            data = [app.UIAxes_2.Children(1).XData',app.UIAxes_2.Children(1).YData', app.UIAxes_2.Children(2).YData'];
            occured_error = 0;
            try
                if ~exist([cd '\Data'],'dir')
                    mkdir([cd '\Data']);
                end
                if ~exist([cd '\Data\Test-' RunID],'dir')
                    mkdir([cd '\Data\Test-' RunID]);
                end
                SaveSensorDataFileName = [cd '\Data\Test-' RunID '\ValveOpeningReal.mat'];
                save(SaveSensorDataFileName,'data');
            catch error
                disp(error);
                occured_error = 1;
                Message(1,1,0,'Data couldn''t be saved', 'KO', RunID);
            end
            
            if ~occured_error
                %Change lamp status
                app.StatusLamp.Color = 'b'; % idle
            else
                %Change lamp status
                app.StatusLamp.Color = 'r'; % error
            end
        end

        % Value changed function: VoltageVEditField_2
        function VoltageVEditField_2ValueChanged(app, event)
            value = app.VoltageVEditField_2.Value;
            % When the field of the voltage has been modified, the following widgets are updated:
            
            %Voltage slider
            %check it is in the limits of the field
            if (value >= app.VoltageVSlider.Limits(1) && value <= app.VoltageVSlider.Limits(end))
                app.VoltageVSlider.Value = value;
            else
                app.VoltageVSlider.Value = app.VoltageVSlider.Limits(1);
            end
            %Angular position edit field
            value_position = Voltage2Position(app, value);
            %check it is in the limits of the field
            if (value_position >= app.PositiondegEditField_2.Limits(1) && value_position <= app.PositiondegEditField_2.Limits(end))
                app.PositiondegEditField_2.Value = value_position;
            else
                app.PositiondegEditField_2.Value = app.PositiondegEditField_2.Limits(1);
            end
            
        end

        % Button pushed function: FullclosetofullopenButton
        function FullclosetofullopenButtonPushed2(app, event)
            %  direct to 0V, wait pos reached, hard to 10V, wait pos reached
            DisableForMovement(app)
            MoveToVoltage(app, 0);
            MoveToVoltage(app, 10);
            EnableAfterMovement(app);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            DisableForMovement(app)
            MoveToVoltage(app, 5);
            EnableAfterMovement(app);
        end

        % Value changed function: DeviceNameEditField
        function DeviceNameEditFieldValueChanged(app, event)
            value = app.DeviceNameEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ActuationPanel = uipanel(app.UIFigure);
            app.ActuationPanel.Title = 'Actuation';
            app.GoButton = uibutton(app.ActuationPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.UIAxes_2 = uiaxes(app.ActuationPanel);
            title(app.UIAxes_2, 'Actual Valve Opening')
            xlabel(app.UIAxes_2, 'Time [s]')
            ylabel(app.UIAxes_2, 'Opening [deg]')
            app.SaveasTXTButton_2 = uibutton(app.ActuationPanel, 'push');
            app.SaveasTXTButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveasTXTButton_2Pushed, true);
            app.SaveasTXTButton_2.Text = 'Save as TXT';
            app.SaveasMATButton_2 = uibutton(app.ActuationPanel, 'push');
            app.SaveasMATButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveasMATButton_2Pushed, true);
            app.SaveasMATButton_2.Text = 'Save as MAT';
            app.SaveasPNGButton_2 = uibutton(app.ActuationPanel, 'push');
            app.SaveasPNGButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveasPNGButton_2Pushed, true);
            app.SaveasPNGButton_2.Text = 'Save as PNG';
            app.RunningLampLabel = uilabel(app.ActuationPanel);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.ActuationPanel);
            app.DataSavingLabel = uilabel(app.ActuationPanel);
            app.DataSavingLabel.Text = 'Data Saving';
            app.STOPButton = uibutton(app.ActuationPanel, 'state');
            app.STOPButton.Text = 'STOP';
            app.OnlystopsafterLabel = uilabel(app.ActuationPanel);
            app.OnlystopsafterLabel.Text = 'Only stops after ';
            app.thecurrentcommandLabel = uilabel(app.ActuationPanel);
            app.thecurrentcommandLabel.Text = 'the current command';
            app.bufferhasfinishedLabel = uilabel(app.ActuationPanel);
            app.bufferhasfinishedLabel.Text = 'buffer has finished';
            app.executingLabel = uilabel(app.ActuationPanel);
            app.executingLabel.Text = 'executing';
            app.ScenarioPanel = uipanel(app.UIFigure);
            app.ScenarioPanel.Title = 'Scenario';
            app.UITable = uitable(app.ScenarioPanel);
            app.UITable.ColumnName = {'Index'; 'Target Flow [l/s]'; 'Time To Target [s]'; 'Hold Time [s]'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.ScenarioPanel);
            title(app.UIAxes, 'Target Flow')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Flow [l/s]')
            app.ChoosefileButton = uibutton(app.ScenarioPanel, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.SaveasPNGButton = uibutton(app.ScenarioPanel, 'push');
            app.SaveasPNGButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasPNGButtonPushed, true);
            app.SaveasPNGButton.Text = 'Save as PNG';
            app.SaveasTXTButton = uibutton(app.ScenarioPanel, 'push');
            app.SaveasTXTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasTXTButtonPushed, true);
            app.SaveasTXTButton.Text = 'Save as TXT';
            app.VerifyButton = uibutton(app.ScenarioPanel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.UnverifiedLabel = uilabel(app.ScenarioPanel);
            app.UnverifiedLabel.Text = 'Unverified';
            app.FlowpositionDropDownLabel = uilabel(app.ScenarioPanel);
            app.FlowpositionDropDownLabel.Text = 'Flow/position';
            app.FlowpositionDropDown = uidropdown(app.ScenarioPanel);
            app.FlowpositionDropDown.Items = {'Flow', 'Position'};
            app.FlowpositionDropDown.ValueChangedFcn = createCallbackFcn(app, @FlowpositionDropDownValueChanged, true);
            app.FlowpositionDropDown.Value = 'Flow';
            app.StatusLampLabel = uilabel(app.ScenarioPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ScenarioPanel);
            app.TestIDEditFieldLabel = uilabel(app.ScenarioPanel);
            app.TestIDEditFieldLabel.Text = 'Test ID';
            app.TestIDEditField = uieditfield(app.ScenarioPanel, 'text');
            app.TestIDEditField.Editable = 'off';
            app.NewIDButton = uibutton(app.ScenarioPanel, 'push');
            app.NewIDButton.ButtonPushedFcn = createCallbackFcn(app, @NewIDButtonPushed, true);
            app.NewIDButton.Text = 'New ID';
            app.ManualControlPanel = uipanel(app.UIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.TabGroup = uitabgroup(app.ManualControlPanel);
            app.SimpleTab = uitab(app.TabGroup);
            app.SimpleTab.Title = 'Simple';
            app.VoltageVSliderLabel = uilabel(app.SimpleTab);
            app.VoltageVSliderLabel.Text = 'Voltage [V]';
            app.VoltageVSlider = uislider(app.SimpleTab);
            app.VoltageVSlider.Limits = [0 10];
            app.VoltageVSlider.ValueChangedFcn = createCallbackFcn(app, @VoltageVSliderValueChanged, true);
            app.PositiondegEditField_2Label = uilabel(app.SimpleTab);
            app.PositiondegEditField_2Label.Text = 'Position [deg]';
            app.PositiondegEditField_2 = uieditfield(app.SimpleTab, 'numeric');
            app.PositiondegEditField_2.Limits = [-5 95];
            app.PositiondegEditField_2.ValueDisplayFormat = '%.3f';
            app.PositiondegEditField_2.ValueChangedFcn = createCallbackFcn(app, @PositiondegEditField_2ValueChanged, true);
            app.GoButtonManual = uibutton(app.SimpleTab, 'push');
            app.GoButtonManual.ButtonPushedFcn = createCallbackFcn(app, @GoButtonManualPushed, true);
            app.GoButtonManual.Text = 'Go';
            app.FullcloseButton = uibutton(app.SimpleTab, 'push');
            app.FullcloseButton.ButtonPushedFcn = createCallbackFcn(app, @FullcloseButtonPushed, true);
            app.FullcloseButton.Text = 'Full close';
            app.ReadButton = uibutton(app.SimpleTab, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.CurrentpositiondegEditFieldLabel = uilabel(app.SimpleTab);
            app.CurrentpositiondegEditFieldLabel.Text = 'Current position [deg]';
            app.CurrentpositiondegEditField = uieditfield(app.SimpleTab, 'numeric');
            app.CurrentpositiondegEditField.ValueDisplayFormat = '%.3f';
            app.CurrentpositiondegEditField.Editable = 'off';
            app.CurrentvoltageVEditFieldLabel = uilabel(app.SimpleTab);
            app.CurrentvoltageVEditFieldLabel.Text = 'Current voltage [V]';
            app.CurrentvoltageVEditField = uieditfield(app.SimpleTab, 'numeric');
            app.CurrentvoltageVEditField.Limits = [-15 15];
            app.CurrentvoltageVEditField.ValueDisplayFormat = '%.3f';
            app.CurrentvoltageVEditField.Editable = 'off';
            app.RampCheckBox = uicheckbox(app.SimpleTab);
            app.RampCheckBox.ValueChangedFcn = createCallbackFcn(app, @RampCheckBoxValueChanged, true);
            app.RampCheckBox.Text = 'Ramp';
            app.VoltageVEditField_2Label = uilabel(app.SimpleTab);
            app.VoltageVEditField_2Label.Text = 'Voltage [V]';
            app.VoltageVEditField_2 = uieditfield(app.SimpleTab, 'numeric');
            app.VoltageVEditField_2.ValueDisplayFormat = '%.3f';
            app.VoltageVEditField_2.ValueChangedFcn = createCallbackFcn(app, @VoltageVEditField_2ValueChanged, true);
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.Button_3 = uibutton(app.AdvancedTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '50%';
            app.FullopenButton = uibutton(app.AdvancedTab, 'push');
            app.FullopenButton.ButtonPushedFcn = createCallbackFcn(app, @FullopenButtonPushed, true);
            app.FullopenButton.Text = 'Full open';
            app.FullclosetofullopenButton = uibutton(app.AdvancedTab, 'push');
            app.FullclosetofullopenButton.ButtonPushedFcn = createCallbackFcn(app, @FullclosetofullopenButtonPushed2, true);
            app.FullclosetofullopenButton.Text = 'Full close to full open';
            app.EditFieldLabel = uilabel(app.AdvancedTab);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.AdvancedTab, 'numeric');
            app.EditField.Value = 0.1;
            app.RampTypeButtonGroup = uibuttongroup(app.AdvancedTab);
            app.RampTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RampTypeButtonGroupSelectionChanged, true);
            app.RampTypeButtonGroup.Title = 'Ramp Type';
            app.SlopeVsButton = uiradiobutton(app.RampTypeButtonGroup);
            app.SlopeVsButton.Text = 'Slope [V/s]';
            app.SlopeVsButton.Value = true;
            app.TimesButton = uiradiobutton(app.RampTypeButtonGroup);
            app.TimesButton.Text = 'Time [s]';
            app.RampParameterLabel = uilabel(app.AdvancedTab);
            app.RampParameterLabel.Text = 'Ramp Parameter';
            app.ConverterTab = uitab(app.TabGroup);
            app.ConverterTab.Title = 'Converter';
            app.VoltageVEditFieldLabel = uilabel(app.ConverterTab);
            app.VoltageVEditFieldLabel.Text = 'Voltage [V]';
            app.VoltageVEditField = uieditfield(app.ConverterTab, 'numeric');
            app.VoltageVEditField.ValueDisplayFormat = '%.2f';
            app.VoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @VoltageVEditFieldValueChanged, true);
            app.PositiondegEditFieldLabel = uilabel(app.ConverterTab);
            app.PositiondegEditFieldLabel.Text = 'Position [deg]';
            app.PositiondegEditField = uieditfield(app.ConverterTab, 'numeric');
            app.PositiondegEditField.ValueDisplayFormat = '%.2f';
            app.PositiondegEditField.ValueChangedFcn = createCallbackFcn(app, @PositiondegEditFieldValueChanged, true);
            app.FlowlsEditFieldLabel = uilabel(app.ConverterTab);
            app.FlowlsEditFieldLabel.Text = 'Flow [l/s]';
            app.FlowlsEditField = uieditfield(app.ConverterTab, 'numeric');
            app.FlowlsEditField.ValueDisplayFormat = '%.3f';
            app.FlowlsEditField.ValueChangedFcn = createCallbackFcn(app, @FlowlsEditFieldValueChanged, true);
            app.OpeningClosingDropDownLabel = uilabel(app.ConverterTab);
            app.OpeningClosingDropDownLabel.Text = 'Opening/Closing';
            app.OpeningClosingDropDown = uidropdown(app.ConverterTab);
            app.OpeningClosingDropDown.Items = {'Opening', 'Closing'};
            app.OpeningClosingDropDown.Value = 'Opening';
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.DeviceNameLabel = uilabel(app.ConfigurationPanel);
            app.DeviceNameLabel.Enable = 'off';
            app.DeviceNameLabel.Text = 'Device Name ';
            app.DeviceNameEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.DeviceNameEditField.ValueChangedFcn = createCallbackFcn(app, @DeviceNameEditFieldValueChanged, true);
            app.DeviceNameEditField.Enable = 'off';
            app.DeviceNameEditField.Value = 'DevX';
            app.NIRatesamplessEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.NIRatesamplessEditFieldLabel.Text = 'NI Rate [samples/s]';
            app.NIRatesamplessEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.NIRatesamplessEditField.Limits = [2 8000];
            app.NIRatesamplessEditField.ValueDisplayFormat = '%.0f';
            app.NIRatesamplessEditField.Value = 10;
            app.PositionTolerancedegEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.PositionTolerancedegEditFieldLabel.Text = 'Position Tolerance [deg]';
            app.PositionTolerancedegEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.PositionTolerancedegEditField.Limits = [0.05 90];
            app.PositionTolerancedegEditField.ValueDisplayFormat = '%.3f';
            app.PositionTolerancedegEditField.Value = 0.22;
            app.ValveSpeedsVEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.ValveSpeedsVEditFieldLabel.Text = 'Valve Speed [s/V]';
            app.ValveSpeedsVEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.ValveSpeedsVEditField.Limits = [0 9999999999];
            app.ValveSpeedsVEditField.ValueDisplayFormat = '%.3f';
            app.ValveSpeedsVEditField.Editable = 'off';
            app.ValveSpeedsVEditField.Enable = 'off';
            app.ValveSpeedsVEditField.Value = 10;
            app.NISatusLampLabel = uilabel(app.ConfigurationPanel);
            app.NISatusLampLabel.Text = 'NI Satus';
            app.NISatusLamp = uilamp(app.ConfigurationPanel);
            app.CheckstatusButton = uibutton(app.ConfigurationPanel, 'push');
            app.CheckstatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckstatusButtonPushed, true);
            app.CheckstatusButton.Text = 'Check status';
            app.V17Label = uilabel(app.UIFigure);
            app.V17Label.Text = 'V1.7';
            app.ValveControlSoftwareviaNIUSBLabel = uilabel(app.UIFigure);
            app.ValveControlSoftwareviaNIUSBLabel.Text = 'Valve Control Software via NI-USB';
            app.NEsnaultLabel = uilabel(app.UIFigure);
            app.NEsnaultLabel.Text = 'N. Esnault';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '11-2020';
            app.Matlab2018bx64Label = uilabel(app.UIFigure);
            app.Matlab2018bx64Label.Text = 'Matlab 2018b x64';
            app.UoAFluidsLabLabel = uilabel(app.UIFigure);
            app.UoAFluidsLabLabel.Text = 'UoA-Fluids Lab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VariableFlowGenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end